classdef EMarker < matlab.apps.AppBase
    %EMARKER Marking events interactively.
    %   Input must be a data structure with the following fields
    %   mat, channel x sample matrix
    %   srate, sampling rate
    %   label, N x 1 cell array
    %   events(optional)
    %   type(optional)

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        OpenMenu                  matlab.ui.container.Menu
        CloseMenu                 matlab.ui.container.Menu
        QuitMenu                  matlab.ui.container.Menu
        EventsMenu                matlab.ui.container.Menu
        SaveMenu                  matlab.ui.container.Menu
        SaveAsMenu                matlab.ui.container.Menu
        LoadMenu                  matlab.ui.container.Menu
        HelpMenu                  matlab.ui.container.Menu
        LogFileMenu               matlab.ui.container.Menu
        SourceFileMenu            matlab.ui.container.Menu
        ShowChannelDropDownLabel  matlab.ui.control.Label
        ShowEventDropDownLabel    matlab.ui.control.Label
        GridLayout                matlab.ui.container.GridLayout
        Label                     matlab.ui.control.Label
        GridLayout14              matlab.ui.container.GridLayout
        GridLayout18              matlab.ui.container.GridLayout
        AddEvButton               matlab.ui.control.Button
        EditField_2Label          matlab.ui.control.Label
        RPeakZthrEF               matlab.ui.control.NumericEditField
        DetectAllBtn              matlab.ui.control.Button
        MinRRDistEF               matlab.ui.control.NumericEditField
        MindistsLabel             matlab.ui.control.Label
        GridLayout17              matlab.ui.container.GridLayout
        ButtonGroup               matlab.ui.container.ButtonGroup
        UnknownButton             matlab.ui.control.RadioButton
        CrossPointButton          matlab.ui.control.RadioButton
        LastTroughButton          matlab.ui.control.RadioButton
        LastPeakButton            matlab.ui.control.RadioButton
        AmplitudechangeButton     matlab.ui.control.RadioButton
        TroughButton              matlab.ui.control.RadioButton
        PeakButton                matlab.ui.control.RadioButton
        PreEvIdxCB                matlab.ui.control.CheckBox
        CrossValEF                matlab.ui.control.NumericEditField
        CrosspointEditFieldLabel  matlab.ui.control.Label
        GridLayout12              matlab.ui.container.GridLayout
        BoxAlignDD                matlab.ui.control.DropDown
        SpinnerBoxSize            matlab.ui.control.Spinner
        BoxsLabel                 matlab.ui.control.Label
        GridLayout16              matlab.ui.container.GridLayout
        EventsListBoxLabel        matlab.ui.control.Label
        RmEvButton                matlab.ui.control.Button
        UpdateEvTypeButton        matlab.ui.control.Button
        CurrentEditField          matlab.ui.control.NumericEditField
        CurrentEditFieldLabel     matlab.ui.control.Label
        Label5                    matlab.ui.control.Label
        EventsListBox             matlab.ui.control.ListBox
        GridLayout15              matlab.ui.container.GridLayout
        GridLayout8               matlab.ui.container.GridLayout
        DisplayLabel_2            matlab.ui.control.Label
        EventDispDD               matlab.ui.control.DropDown
        AddEvTypeButton           matlab.ui.control.Button
        ChannelDispDD             matlab.ui.control.DropDown
        ColorButton               matlab.ui.control.Button
        EventColorLabel           matlab.ui.control.Label
        EventtypeLabel            matlab.ui.control.Label
        EvTypeEditField           matlab.ui.control.EditField
        DownButton                matlab.ui.control.Button
        RmEvTypeButton            matlab.ui.control.Button
        MvEvTypeUpButton          matlab.ui.control.Button
        EvTypeListBox             matlab.ui.control.ListBox
        TrialLabel                matlab.ui.control.Label
        TrialDropDown             matlab.ui.control.DropDown
        LockCheckBox              matlab.ui.control.CheckBox
        ClearButton               matlab.ui.control.Button
        Button_5                  matlab.ui.control.Button
        HideLabel                 matlab.ui.control.Button
        ShowLabel                 matlab.ui.control.Button
        Button_2                  matlab.ui.control.Button
        RemlChannelLB             matlab.ui.control.ListBox
        DispChannelLB             matlab.ui.control.ListBox
        AllLabel                  matlab.ui.control.Label
        DisplayLabel              matlab.ui.control.Label
        GridLayout10              matlab.ui.container.GridLayout
        EditField4_3              matlab.ui.control.EditField
        GotoLabel                 matlab.ui.control.Label
        GridLayout13              matlab.ui.container.GridLayout
        EndClockEditField         matlab.ui.control.EditField
        EndClockEditFieldLabel    matlab.ui.control.Label
        StartClockEditField       matlab.ui.control.EditField
        StartClockEditFieldLabel  matlab.ui.control.Label
        ResetButton               matlab.ui.control.Button
        ScaleinWindowButton       matlab.ui.control.StateButton
        AmplitudeSpinner          matlab.ui.control.Spinner
        TimeSpinner               matlab.ui.control.Spinner
        TimeSpinnerLabel          matlab.ui.control.Label
        StatusLabel               matlab.ui.control.Label
        GridLayout5               matlab.ui.container.GridLayout
        Button_ForwardMinor       matlab.ui.control.Button
        Button_Forward            matlab.ui.control.Button
        Button_BackwardMinor      matlab.ui.control.Button
        Button_Backward           matlab.ui.control.Button
        UIAxesN                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        nav % navigation
        data % time series
        misc %
    end

    properties (Access = public)
        evs
    end

    methods (Access = private)

        function evs = GetCrossPnt( ~, vec, ref)
            options.occur = 'last';
            norm_vec = vec - ref;
            N = length( norm_vec);

            v1 = norm_vec( 1 : N-1);
            v2 = norm_vec( 2 : N);
            mat = v1 .* v2 <= 0;

            [~, ~, ~, clusts] = G_ClustStats1d( mat, mat, 'sum');
            nbclusts = length( clusts);
            evs = nan( nbclusts, 1);

            % closest point
            if strcmpi( options.occur, 'last')
                for k = 1 : nbclusts
                    loc = clusts{ k};
                    tmp = abs( norm_vec( [loc, loc(end)+1]));
                    [~, midx] = min( fliplr( tmp));
                    evs(k) = loc(1) + length( tmp) - midx;
                end

            elseif strcmpi( options.occur, 'first')
                for k = 1 : nbclusts
                    loc = clusts{ k};
                    tmp = abs( norm_vec( [loc, loc(end)+1]));
                    [~, midx] = min( tmp);
                    evs(k) = loc(1) + midx - 1;
                end

            else
                error( 'unknown options.');
            end
        end


        function click_ax = IClickMainAxes( app)
            ax_xy = app.UIAxes.CurrentPoint( 1, 1:2);
            click_ax = prod( ax_xy(2) - [0, 1]) <= 0 && prod( ax_xy(1) - app.nav.data_win) <= 0;
        end


        function [ylm, bd] = RetrieveYlim( app, varargin)
            % varargin{1}, current point in axes
            % 
            % ylm, display channel x 2 
            % bd, rescale boundary

            n_disp = numel( app.DispChannelLB.Items);
            ylm = linspace( 0, 1, n_disp+1);
            margin = 0.1 * 1/n_disp;
            bd = nan( n_disp, 2);
            for k = 1 : n_disp
                bd( n_disp-k+1, :) = [ylm(k)+margin, ylm(k+1)-margin];
            end
            ylm = fliplr( ylm);
            ylm = [ylm(1:end-1); ylm(2:end)]';

            if isempty( varargin)
                return;
            end

            % update selected channel
            if n_disp < 1
                app.data.index = [];
                return;
            end

            ax_xy = app.UIAxes.CurrentPoint( 1, 1:2)';
            for k = 1 : n_disp
                cur_chnl = app.DispChannelLB.Items( k);
                xy_in = ax_xy - [app.UIAxes.XLim; ylm( k, :)];
                xy_in = all( prod( xy_in, 2) <= 0);
                if xy_in
                    [~, app.data.index] = ismember( cur_chnl, app.data.label);
                    break;
                end
            end

            if app.LockCheckBox.Value ~= 1
                app.DispChannelLB.Value = cur_chnl;
            end
        end


        function UpdateWin( app, n, varargin)
            % Update start and end location of current window
            % n, positive - move forward
            % n, negative - move backward
            % varargin{1} - true - set n as start point
            if isempty( app.data.mat)
                Redraw( app);
                return;
            end

            if nargin < 3
                is_start = false;
            else
                is_start = varargin{1};
            end

            if ~is_start
                win_lo = max( [app.data.t(1), app.nav.win(1) + n]);
            else
                win_lo = n;
            end

            win_lo = min( [win_lo, app.data.t( end)]);
            win_hi = win_lo + app.TimeSpinner.Value;
            data_win = [win_lo, min( [win_hi, app.data.t( end)])];

            orig_win = app.nav.win;
            orig_data_win = app.nav.data_win;
            orig_cursor = app.misc.cursor;

            app.nav.win = [win_lo, win_hi];
            app.nav.data_win = data_win;

            % error;
            % samp_ind = app.data.srate * d_win;
            prec = 1e-12;
            samp_ind = app.data.t >= data_win(1) - prec & app.data.t <= data_win(2) + prec;
            app.nav.data_ind = [find( samp_ind>0, 1, 'first'), find( samp_ind>0, 1, 'last')];

            if isempty( orig_win)
                orig_win = app.nav.win;
            end

            if isempty( orig_data_win)
                orig_data_win = app.nav.data_win;
            end

            % cursor and box data
            if isempty( orig_cursor)
                orig_cursor = mean( orig_data_win);
            end

            orig_c = (orig_cursor - orig_win(1)) / diff( orig_win);
            app.misc.cursor = win_lo + diff( app.nav.win) * orig_c;
            box_sz = app.SpinnerBoxSize.Value;
            if app.nav.win < box_sz
                app.misc.box.xdata = app.nav.win( [1, 2, 2, 1, 2]);
            else
                if strcmpi( app.misc.box_align, 'center')
                    b_lo = max( [win_lo, app.misc.cursor - box_sz/2]);
                    b_hi = min( [app.misc.cursor + box_sz/2, win_hi]);

                elseif strcmpi( app.misc.box_align, 'right')
                    b_lo = max( [win_lo, app.misc.cursor - box_sz]);
                    b_hi = min( [app.misc.cursor, win_hi]);

                else
                    b_lo = max( [win_lo, app.misc.cursor]);
                    b_hi = min( [app.misc.cursor + box_sz, win_hi]);
                end

                app.misc.box.xdata = [b_lo, b_hi, b_hi, b_lo, b_lo];
            end

            Redraw( app);
        end % update time window length


        function Redraw(app)
            % currently selected event, that is within current window
            if isempty( app.data.mat)
                return;
            end

            app.misc.current_ev = [];

            cla( app.UIAxes);
            if isempty( app.data.mat)
                text( app.UIAxes, 0.5, 0.5, 'Nothing to plot', ...
                    'FontSize', 36, 'color', 'b', 'fontname', 'Arial', ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'middle');

                set( app.UIAxes, 'Xlim', [0, 1], 'xtick', [], 'ylim', [0, 1], 'ytick', []);
                return;
            end

            if isempty( app.data.index)
                return;
            end

            % time series
            d_win = app.nav.data_win;
            % % d_win_samp = app.data.srate * d_win;% off by 1
            % prec = 1e-12;
            % samp_ind = app.data.t >= d_win(1) - prec & app.data.t <= d_win(2) + prec;
            % d_win_samp = [find( samp_ind>0, 1, 'first'), find( samp_ind>0, 1, 'last')];
            d_win_samp = app.nav.data_ind;
            samp_ind = d_win_samp(1) : d_win_samp(2);
            
            % events within current window
            ev_ind = find( app.data.events.Location >= d_win_samp(1) & ...
                app.data.events.Location <= d_win_samp(2));
            win_ev = app.data.events( ev_ind, :);

            % selected events in the Event list box
            sel_ev_ind = [];
            if ~isempty( app.EventsListBox.ItemsData)
                sel_ev_ind = EventIndex( app, app.EventsListBox.Value);
            end

            sel_ev_loc = app.data.events.Location( sel_ev_ind);
            sel_ev_type = app.data.events.Type( sel_ev_ind);
            tmp_ind = find( sel_ev_loc >= d_win_samp(1)& sel_ev_loc <= d_win_samp(2) );
            sel_ev_loc = sel_ev_loc( tmp_ind);
            sel_ev_type = sel_ev_type( tmp_ind);

            [~, bd] = RetrieveYlim( app);
            ytk = bd';
            ln_prop = {'color', 'b', 'linewidth', 1, 'linestyle', '-'};
            nb_disp_chnl = numel( app.DispChannelLB.Items);
            res_mat = nan( nb_disp_chnl, numel( samp_ind));
            ytk_lbl = cell( nb_disp_chnl, 1);
            for ch_idx = 1 : nb_disp_chnl
                cur_ch = app.DispChannelLB.Items{ ch_idx};
                if strcmp( cur_ch, app.data.label{ app.data.index})
                    ln_ck = 'b';
                    % cur_ch_idx = ch_idx;
                else
                    ln_ck = 0.2 * ones( 1, 3);
                end

                [~, ch_loc] = ismember( cur_ch, app.data.label);
                if app.ScaleinWindowButton.Value == 1
                    [lo, hi] = bounds( app.data.mat( ch_loc, samp_ind));
                else
                    [lo, hi] = bounds( app.data.mat( ch_loc, :));
                end
                % % Use current time window
                % [lo, hi] = bounds( app.data.mat( ch_loc, samp_ind));
                ch_bd = [lo, hi];
                amp_scale = [-0.5, 0.5]*100/app.AmplitudeSpinner.Value;
                ch_bd = mean( ch_bd) + diff( ch_bd) * amp_scale;
                res_mat( ch_idx, :) = G_Rescale( app.data.mat( ch_loc, samp_ind), bd( ch_idx, :), 'bounds', ch_bd);
                ytk_lbl{ ch_idx} = cellstr( string( ch_bd));
                line( app.UIAxes, app.data.t( samp_ind), res_mat( ch_idx, :), ln_prop{:}, 'color', ln_ck);

                % plot marker of current selected channel
                ch_ev = win_ev( ismember( win_ev.Label, cur_ch), :);
                ev_loc = ch_ev.Location;
                u_loc = unique( ev_loc);
                for k = 1 : length( u_loc)
                    tloc = u_loc( k);
                    ia = find( ev_loc == tloc);
                    if any( tloc == sel_ev_loc)
                        tmp_type = sel_ev_type{ find( tloc == sel_ev_loc, 1, 'last')};
                    else
                        tmp_type = ch_ev.Type{ ia( end)};
                    end

                    [~, j] = ismember( tmp_type, app.misc.event_type( :, 1));
                    ck = app.misc.event_type{ j, 2};

                    tmp_mk = 'o';
                    if length( ia) > 1
                        tmp_mk = '^';
                    end

                    xx = app.data.t( tloc);
                    rel_tloc = int32( tloc - d_win_samp(1) + 1);
                    yy = res_mat( ch_idx, rel_tloc);
                    h = line( app.UIAxes, xx, yy, 'marker', tmp_mk, ...
                        'markersize', 8, 'markerfacecolor', ck, ...
                        'markeredgecolor', 'none',...
                        'color', ck, 'linewidth', 1, 'linestyle', 'none');

                    if any( tloc == sel_ev_loc) && isscalar( sel_ev_loc)
                        app.misc.current_ev.h = h;
                        app.misc.current_ev.index = sel_ev_ind;
                        app.misc.current_ev.mutable = app.data.events.Mutable( sel_ev_ind);
                    end
                end

                % Put a big circle around currently selected events
                loc = unique( sel_ev_loc);
                if ~isempty( loc)
                    xx = app.data.t( loc);
                    loc = int32( loc - d_win_samp(1) + 1);
                    line( app.UIAxes, xx, res_mat( ch_idx, loc), ...
                        'marker', 'o', 'linestyle', 'none', ...
                        'markersize', 12, 'markerfacecolor', 'none', ...
                        'markeredgecolor', 'r', 'linewidth', 1);
                end
            end

            [ytk, sidx] = sort( ytk(:));
            ytk_lbl = cat( 2, ytk_lbl{:});
            ytk_lbl = ytk_lbl( sidx);

            %app.UIAxes.YAxisLocation = 'right';
            app.UIAxes.XLim = [app.nav.win(1), app.nav.win(2)];

            % xtick labels
            % need to figure out how many xticks to display
            n_xtk = 5;
            xtk = linspace( app.nav.win(1), app.nav.win(2), n_xtk);            
            if strcmpi( app.misc.time_format, 'seconds')
                xtk_lbl = cellstr( string( xtk));

            else
                s_clock = app.data.start_clock;
                if diff( app.nav.win) <= 1
                    s_clock = datetime( s_clock, 'format', 'yyyy-MM-dd HH:mm:ss.SSS');
                end

                clock_str = s_clock + seconds( xtk - app.data.t(1));
                xtk_lbl = string( clock_str);
                xtk_lbl = cellfun( @(a) extractAfter( a, ' '), xtk_lbl, 'un', 0);
            end

            app.UIAxes.XTick = xtk;
            %app.UIAxes.TickDir = 'out';
            app.UIAxes.XTickLabel = {};
            % ytick labels
            ylm = [0, 1];
            app.UIAxes.YLim = ylm;            
            app.UIAxes.YTick = ytk;
            % app.UIAxes.YTickLabel = cellstr( string( ytk));
            % ytick label inside the axes
            app.UIAxes.YTickLabel = ytk_lbl;

            % tick font properties
            tk_prop = {'fontname', app.misc.fontname, 'color', app.misc.fontcolor,...
                'fontweight', app.misc.fontweight, 'fontsize', app.misc.fontsize,...
                'horizontalalignment', 'center', 'verticalalignment', 'top'};

            % xtick label
            text( app.UIAxes, xtk, ylm(1) * ones( size( xtk)), xtk_lbl, tk_prop{:});

            % box of selection
            b_width = diff( unique( app.misc.box.xdata));
            if abs( b_width - app.SpinnerBoxSize.Value) < 1e-10
                box_lnc = [248 108 231]/255;
                set( app.StatusLabel, 'Text', '');
            else
                box_lnc = [254 153 0]/255;
                set( app.StatusLabel, 'Text', 'Selection box trimmed.', 'fontcolor', 'r');
            end

            box_prop = {'linestyle', '--', 'linewidth', 0.5, 'color', box_lnc};
            cursor_prop = {'color', box_lnc, 'linestyle', '-', 'linewidth', 0.5};
            y = ylm( [1, 1, 2, 2, 1]) + [1, 1, -1, -1, 1] * 0.05;
            line( app.UIAxes, app.misc.box.xdata, y, box_prop{:});
            line( app.UIAxes, app.misc.cursor + [0, 0], ylm, cursor_prop{:});

            % -------------------------------------------------------------
            % navigation bar
            total_t = diff( app.data.t( [1, end]));
            set( app.UIAxesN, 'Units', 'centimeters');
            ax_wcm = app.UIAxesN.Position(3);
            set( app.UIAxesN, 'Units', 'normalized');
            % width of the bar
            bar_w = app.TimeSpinner.Value / total_t;
            bar_wcm = bar_w * ax_wcm;
            % set a minimal width
            bar_wcm = max( [0.2, bar_wcm]);
            bar_w_ratio = diff( d_win) / diff( app.nav.win);
            if bar_w_ratio < 1
                bar_wcm = bar_wcm * bar_w_ratio;
                x_hi = 1;
                x_lo = 1 - bar_wcm / ax_wcm;
            else
                x_lo = d_win(1) / total_t;
                bar_w = bar_wcm / ax_wcm;
                x_hi = min( [1, x_lo + bar_w]);
                x_lo = x_hi - bar_w;
            end

            x = [x_lo, x_hi, x_hi, x_lo];
            if isempty( app.nav.patch)
                app.nav.patch = fill( app.UIAxesN, x, [0 0 1 1], 'b');
            else
                app.nav.patch.Vertices = [x', [0; 0; 1; 1]];
            end
            % end of navigation bar
            % -------------------------------------------------------------

            % Move eventlist box
            loc = find( app.data.t( app.data.events.Location) <= d_win(2), 1, 'last');
            if ~isempty( loc)
                app.EventsListBox.scroll( app.EventsListBox.ItemsData( loc));
            end

            grid( app.UIAxes, 'on');
            grid( app.UIAxes, 'minor');
        end % update plot


        function IMoveItems(app, n, d)
            % name of list box
            % direction, 'up' or 'down'
            sel_val = app.(n).Value;
            all_val = app.(n).Items;
            if isempty( sel_val) || isempty( all_val)
                % nothing to move
                return;
            end

            if ~iscell( sel_val)
                % one item selected
                sel_val = {sel_val};
            end

            d = lower( d);
            if ~ismember( d, {'up', 'down'})
                error( 'd must be up or down');
            end

            N = length( all_val);
            [~, loc] = ismember( sel_val, all_val);
            if strcmpi( d, 'up')
                if min( loc) == 1
                    % cannot move the first item up
                    return;
                end

                new_loc = loc - 1;

            else
                if max( loc) == N
                    % cannot move the last item down
                    return;
                end

                new_loc = loc + 1;
            end

            s = cell( N, 1);
            s( new_loc) = sel_val;
            all_val( loc) = [];
            idx = 1 : N;
            idx( new_loc) = [];
            s( idx) = all_val;
            app.(n).Items = s;
        end % move items up and down


        function IUpdateEvents( app, action, cur_ev, orig_ev)
            % action, 'add', 'update', 'remove', 'init'
            % cur_ev, table
            %   Label, Location, Type, Mutable

            if nargin < 4
                orig_ev = [];
            end

            if isempty( cur_ev)
                return;
            end

            % st
            [ia, loc] = ismember( cur_ev, app.data.events);

            ev2hil = [];
            switch lower( action)
                case 'init'

                case 'add'
                    ev = cur_ev( ~ia, :);
                    ev_hist = repmat( {'add'}, size( ev, 1), 1);
                    app.data.events = [app.data.events; ev];
                    if ~isempty( ev)
                        ev2hil = ev( 1, :);
                    end

                case 'update'
                    if ~isempty( orig_ev)
                        % update location of the selected event
                        if ~isempty( app.misc.current_ev)
                            ev2hil = app.data.events( app.misc.current_ev.index, :);
                        end

                        ev_hist = {'remove', 'add'};
                        app.data.events = [app.data.events; cur_ev];
                        app.data.events( app.misc.current_ev.index, :) = [];
                        ev = [orig_ev; cur_ev];

                    else
                        % update event type of selected events
                        % currently selected event types
                        ev_type = app.EvTypeListBox.Value;
                        if ~iscell( ev_type)
                            % one item was selected
                            ev_type = {ev_type};
                        end
                        ev_type = ev_type(:);
                        nb_ev_type = numel( ev_type);

                        ev = [];
                        nb_cur_ev = size( cur_ev, 1);
                        for k = 1 : nb_cur_ev
                            tmp = repmat( cur_ev( k, :), nb_ev_type, 1);
                            tmp.Type = ev_type;
                            ev = [ev; tmp];
                        end

                        ev_hist = cat( 1, repmat( {'add'}, size( ev, 1), 1),...
                            repmat( {'remove'}, nb_cur_ev, 1));
                        % add new events
                        app.data.events = [app.data.events; ev];
                        ev2hil = ev( 1, :);
                        ev = [ev; app.data.events( loc, :)];
                        % remove original
                        app.data.events( loc, :) = [];
                    end

                case 'remove'                   
                    idx2rm = loc(1);
                    loc( app.data.events.Mutable( loc) == 0) = [];
                    ev = app.data.events( loc, :);
                    app.data.events( loc, :) = [];
                    if ~isempty( app.data.events)
                        idx = ismember( 1:size( app.data.events, 1), idx2rm);
                        ev2hil = app.data.events( idx, :);
                    end

                    ev_hist = repmat( {'remove'}, numel( loc), 1);

                otherwise
                    % unknown action
            end

            app.misc.data_saved = false;

            % remove duplicates
            N = size( app.data.events, 1);
            [tmp, ia] = unique( app.data.events);
            if N > 0 && (size( tmp, 1) ~= N)
                ev = [ev; app.data.events( ~ia, :)];
                app.data.events = tmp;
                ev_hist = [ev_hist, repmat( {'remove'}, sum( ~ia), 1)];
            end

            % action history
            for k = 1 : size( ev_hist, 1)
                fprintf( app.data.logfile_fid, '%s; %s; %d; %s; %d\n', ...
                    ev_hist{ k}, ev.Label{k}, ev.Location( k), ev.Type{k}, ev.Mutable(k));
            end

            % No events
            if isempty( app.data.events)
                set( app.EventsListBox, 'Value', {}, 'Items', {}, 'ItemsData', {});
                Redraw( app);
                return;
            end

            % 1. Sort Location
            % 2. Sort event types at the same location according to their
            %    locations in the event type list box
            [~, sidx] = sort( app.data.events.Location);
            app.data.events = app.data.events( sidx, :);
            u_ev = unique( app.data.events.Location);
            for k = 1 : numel( u_ev)
                ind = find( app.data.events.Location == u_ev( k));
                if isscalar( ind)
                    continue;
                end

                [~, sidx] = ismember( app.data.events.Type( ind), app.EvTypeListBox.Items);
                [~, sidx] = sort( sidx);
                app.data.events( ind, :) = app.data.events( ind( sidx), :);
            end

            % update event list box
            IUpdateEvenListbox( app, ev2hil);
        end % update events


        function IUpdateEvenListbox( app, cur_ev, ~)
            if isempty( app.data.mat)
                return;
            end

            if nargin < 3
                re_draw = true;
            else
                re_draw = false;
            end

            N = size( app.data.events, 1);
            if N == 0
                set( app.EventsListBox, 'Value', {}, 'Items', {}, 'ItemsData', {});
                return;
            end

            item = cell( N, 1);
            item_data = cell( N, 1);
            for k = 1 : N
                ch = app.data.events.Label{k};
                loc = int32( app.data.events.Location( k));
                typ = app.data.events.Type{ k};
                m = app.data.events.Mutable( k);
                % type across all channels
                n_total = sum( ismember( app.data.events.Type, typ));                
                n = sum( ismember( app.data.events.Type( 1 : k), typ));
                item{ k} = sprintf( '%d (%d/%d). %s: %s -- %s    %d', ...
                    k, n, n_total, ch, num2str( app.data.t( loc)), typ, m);
                item_data{ k} = sprintf( '%s; %d; %s; %d', ch, loc, typ, m);
            end

            set( app.EventsListBox, 'Items', item, 'ItemsData', item_data);

            if isempty( cur_ev)
                set( app.EventsListBox, 'Value', {});
            else
                loc = EventIndex( app, cur_ev);
                app.EventsListBox.Value = app.EventsListBox.ItemsData( loc);
            end

            % % ECG R peak detect outliers
            % if N > 2 
            %     rr_dur = diff( app.data.events.Location);
            %     if strcmpi( app.OutlierZMethodDB.Value, 'normal fit')
            %         [avg, sd] = normfit( rr_dur);
            %         rr_durz = (rr_dur - avg)/sd;
            %     else
            %         rr_durz = zscore( rr_dur);
            %     end
            % 
            %     ind = abs( rr_durz) > app.OutlierZThrEF.Value;
            % 
            %     srate = app.data.srate;
            %     ind2 = rr_dur/srate < 0.2 | rr_dur/srate > 1.5;
            %     ind = ind | ind2;
            %     % set( app.OutlierListBox, 'Items', item( ind), 'ItemsData', item_data( ind));                
            % 
            %     app.Label.Text = sprintf( 'RR range: %g - %g s', min( rr_dur)/srate, max( rr_dur)/srate);
            % end

            if re_draw
                Redraw( app);
            end
        end


        function IRecoverEvents( app)
            if isempty( app.data.mat)
                set( app.StatusLabel, 'Fontcolor', 'r',...
                    'Text', 'Load data to continue ...');
                return;
            end

            % recover events from history file            
            [nam, p] = uigetfile( {'*.mat', '*.txt'}, 'Select event file.');

            if isequal( p, 0) || isequal( nam, 0)
                set( app.StatusLabel, 'FontColor', 'b',...
                    'Text', 'Event loading canceled.');
                return;
            end

            src_file = fullfile( p, nam);
            if endsWith( nam, '.mat')
                src_ev = load( src_file);
                fn = fieldnames( src_ev);
                tbl_ind = cellfun( @(x) istable( src_ev.(x)), fn);
                if sum( tbl_ind) ~= 1
                    set( app.StatusLabel, 'FontColor', 'r',...
                        'Text', 'Invalid event. See Command Window for details...');

                    fprintf( ['%s must has a table with the follwing variables: ' ...
                        'Label, Location, Type, Mutable'], src_file);
                    return;
                else                    
                    src_ev = src_ev.( fn{ tbl_ind});
                end

                src_type = unique( src_ev.Type);
                ia = ismember( app.EvTypeListBox.Items, src_type);
                src_type = src_type( ~ia);
                if ~isempty( src_type)
                    app.EvTypeEditField.Value = strjoin( src_type, ';');
                    AddEvTypeButtonPushed( app);
                end

                IUpdateEvents( app, 'add', src_ev);

            else
                % history file
                src_ev = readtable( src_file);
                src_type = unique( src_ev.Type);
                ia = ismember( app.EvTypeListBox.Items, src_type);
                src_type = src_type( ~ia);
                if ~isempty( src_type)
                    app.EvTypeEditField.Value = strjoin( src_type, ';');
                    AddEvTypeButtonPushed( app);
                end

                src_ev2 = src_ev;
                src_ev2 = removevars( src_ev2, 'Action');
                for k = 1 : size( src_ev, 1)
                    IUpdateEvents( app, src_ev.Action{ k}, src_ev2( k, :));
                end
            end
        end % load events from file


        function InitializeUI(app)
            % fill object indicate location of current window
            app.nav.patch = [];
            % start and end location of current window
            app.nav.win = [];
            % real data range
            app.nav.data_win = [];
            app.nav.data_ind = [];

            app.misc.cursor = [];
            app.misc.current_ev = [];
            app.misc.current_ev.h = [];
            app.misc.current_ev.index = [];
            app.misc.current_ev.mutable = [];
            app.misc.start_move = false;
            app.misc.data_saved = false;

            set( app.EventsListBox, 'Value', {}, 'Items', {}, 'ItemsData', {});
            app.EvTypeListBox.Items = app.misc.event_type( :, 1);
            app.EvTypeListBox.Value = app.misc.event_type{ 1};
            EvTypeListBoxValueChanged( app);

            % fill object indicate location of current window
            app.nav.patch = [];
            % start and end location of current window
            app.nav.win = [];
            % real data range
            app.nav.data_win = [];

            cla( app.UIAxes);
            hold( app.UIAxesN, 'on');
            line( app.UIAxesN, [0, 1, 1, 0, 0], [0, 0, 1, 1, 0], 'linewidth', 2, 'color', 'r');
            set( app.UIAxesN, 'xlim', [0, 1], 'xtick', [], 'ylim', [0, 1], 'ytick', []);

            app.TimeSpinner.Limits = [0.01, Inf];
            disableDefaultInteractivity( app.UIAxes);
            disableDefaultInteractivity( app.UIAxesN);
            app.UIAxes.Toolbar.Visible = 'off';
            app.UIAxesN.Toolbar.Visible = 'off';
        end % initialize UI


        function InitializeData( app)
            app.data = [];
            app.data.mat = [];
            app.data.label = {};
            app.data.index = []; % original channel index
            app.data.trial = []; % trial index
            app.data.srate = [];
            app.data.t = [];
            app.data.start_clock = '';
            app.data.source_file = '';            
            app.data.log_file = '';
            app.data.save_file = '';
            app.data.results_saved = false;
            app.data.events = MyEv( app);
            app.data.filename = '';
        end


        function ev = MyEv( ~, lbl, loc, typ, mut)
            % Returns empty event structure
            % ev = MyEv( app)
            % ev = MyEv( app, event_structure)
            % ev = MyEv( app, lbl, loc, typ, mut)

            ev = struct2table( struct( 'Label', {}, 'Location', [], 'Type', {}, 'Mutable', []));
            if nargin < 2                
                return;
            end

            if nargin == 2
                ev_struc = lbl;
                if isempty( ev_struc)
                    return;
                end

                if istable( ev_struc)
                    ev = ev_struc;
                    return;
                end
            end

            if ~iscell( lbl)
                lbl = {lbl};
            end
     
            if ~iscell( typ)
                typ = {typ};
            end

            loc = loc(:);
            ev_sz = size( loc);
            if length( loc) ~= length( typ)
                if numel( typ) > 1
                    error( 'Event type length must match with the number of locations.');
                end

                typ = repmat( typ(1), ev_sz);
            end

            s = [];
            s.Label = repmat( lbl(1), ev_sz);
            s.Location = loc;
            s.Type = typ;
            s.Mutable = mut;
            ev = struct2table( s);          
        end


        function IOpenLog( app, varargin)
            % varargin{1} data file in full path
            % 
            % Output
            % Update app.data.logfile and app.data.logfile_fid
            % 
            if ~isempty( varargin)
                % load from file
                [p, nam] = fileparts( varargin{1});
                app.data.logfile = fullfile( p, [nam, '_EMarker_History.txt']);

            else
                % default log folder to script directory
                d = fileparts( mfilename( 'fullpath'));
                d = fullfile( d, 'EMarker_History');
                if ~exist( d, 'dir')
                    mkdir( d);
                end

                prefix = 'EMarker_History_';
                N = 4;
                cnt = 0;
                fmt = sprintf( '%s%%0%dd.txt', prefix, N);
                app.data.logfile = fullfile( d, sprintf( fmt, cnt));
                while isfile( app.data.logfile)
                    cnt = cnt + 1;
                    if cnt > 10^N - 1
                        N = N + 1;
                        cnt = 1;
                    end
                    app.data.logfile = fullfile( d, sprintf( fmt, cnt));
                end
            end

            app.data.logfile_fid = fopen( app.data.logfile, 'a+');
        end


        function ICloseLog( app, varargin)
            SaveResults( app);
            fclose( app.data.logfile_fid);
        end


        function LoadData(app, x)
            % x - data structure
            %   - full-path-to-file
            if isempty( x)                
                return;
            end

            if ischar( x)
                IOpenLog( app, x);
                x = load( x);
            else
                IOpenLog( app);
            end

            fn = fieldnames( x);
            % valid_nam = {'mat', 'srate', 'label', 'events', 'type'};
            %if ~all( ismember( fn, valid_nam))
            %    error( 'data structure with the following fields: %s', strjoin( valid_nam, ', '));
            %end

            % required fields
            if ~all( ismember( {'mat'}, fn))
                error( 'mat and srate are required.');
            end
   
            % channel x sample
            [nb_ch, N] = size( x.mat);

            % optional fields: start_clock, evs, label, type
            if ~ismember( 'srate', fn)
                srate = 1;
            else
                srate = x.srate;
            end

            label = x.label;
            if ~isempty( label) && ~iscell( label)
                label = {label};
            end

            if numel(label) ~= nb_ch
                label = arrayfun( @(x) sprintf( 'ch%02d', x), 1:nb_ch, 'un', 0);
            end

            if isfield( x, 'start_clock')
                sc = x.start_clock;
            else
                sc = '2000-01-01 00:00:00';
            end

            if isfield( x, 'events')
                ev = x.events;
            else
                ev = [];
            end

            % data matrix
            app.data.t = linspace( 0, (N - 1) / srate, N);
            app.data.mat = x.mat;
            app.data.srate = srate;
            app.data.label = label;
            app.data.start_clock = datetime( sc, 'InputFormat', 'yyyy-MM-dd HH:mm:ss' );

            if isempty( ev)
                s = MyEv( app);

            else
                if istable( ev)
                    s = ev;
                    typ = unique( ev.Type, 'stable');

                else
                    if ~isfield( x, 'type') || isempty( x.type)
                        typ = 'Unset';
                    else
                        typ = x.type;
                    end

                    if ~iscell( typ)
                        typ = {typ};
                    end

                    ev = ev(:);
                    ev_sz = size( ev);
                    if length( ev) ~= length( typ)
                        typ = repmat( typ(1), ev_sz);
                    end

                    s = [];
                    s.Label = repmat( label(1), ev_sz);
                    s.Location = ev;
                    s.Type = typ;
                    s.Mutable = ones( ev_sz);
                    s = struct2table( s);
                end

                ia = ismember( typ, app.EvTypeListBox.Items);
                typ = typ( ~ia);
                for k = 1 : length( typ)
                    app.EvTypeEditField.Value = typ{ k};
                    AddEvTypeButtonPushed( app);
                end
            end

            % add headers
            n = 0;
            tline = fgetl( app.data.logfile_fid);
            while ischar( tline)
                tline = fgetl( app.data.logfile_fid);
                n = n+1;
            end

            if n < 1
                fprintf( app.data.logfile_fid, 'Action; Label; Location; Type; Mutable\n');
            end      

            app.data.index = 1;
            app.data.events = s;
            IUpdateEvenListbox( app, [], false);

            app.DispChannelLB.Items = label;
            if ~isempty( label)
                app.DispChannelLB.Value = label{1};
            else
                app.DispChannelLB.Value = {};
            end
            app.SpinnerBoxSize.Limits = [0, app.TimeSpinner.Value];

            if ~isempty( app.data.mat)
                N = size( app.data.mat, 2);
                app.TimeSpinner.Limits = [0.5/app.data.srate, N/app.data.srate];
            end

            clear x
        end % load data

        
        function MoveEvent(app, d)
            if isempty( app.EventsListBox.Items)
                return;
            end

            loc = find( ismember( app.EventsListBox.ItemsData, app.EventsListBox.Value));
            N = length( app.EventsListBox.Items);
            if strcmpi( d, 'up')
                loc = loc - 1;
                loc( loc < 1) = 1;
                loc = unique( loc);
            else
                loc = loc + 1;
                loc( loc > N) = N;
                loc = unique( loc);
            end
            
            app.EventsListBox.Value = app.EventsListBox.ItemsData( loc);
            EventsListBoxValueChanged( app);
        end % move selections in event list box

        
        function SaveResults( app)
            if isempty( app.data.mat)
                app.StatusLabel.Text = 'Nothing to save';
                return;
            end

            ev = app.data.events;

            if isfield( app.data, 'save_file') && ~isempty( app.data.save_file)
                save_file = app.data.save_file;

            else
                [fn, p] = uiputfile( '*.mat', 'Save the results:');
                if isequal(fn, 0) || isequal( p,0)
                    save_file = '';
                else
                    save_file = fullfile( p, fn);
                end

                app.data.save_file = save_file;
            end

            if ~isempty( save_file)
                source_file = app.data.filename;
                save( save_file, 'ev', 'source_file', '-v7.3');
                writetable( ev, [save_file, '.txt'], 'WriteVariableNames', true);

                app.misc.data_saved = true;
            else
                app.StatusLabel.Text = 'Results were not saved.';
            end
        end


        function [loc, nbinters, last_pnt] = SingleCrossThreshold( ~, v, ref)
            % threshold of single cross
            [~, val2test] = hist( v, length( v));
            nbval2test = length( v);
            loc = zeros( nbval2test, 1);
            % number of intersecs
            nbinters = zeros( nbval2test, 1);
            for idx = 1 : nbval2test
                tmp = GetCrossPnt(app, v, val2test( idx));
                nbinters( idx) = length( tmp);
                loc( idx) = tmp( end);
            end

            tmp = flipud( nbinters(:));
            tmp = find( tmp > 1);
            if ~isempty( tmp)
                if all( nbinters(2:end) >= 2) && nbinters(1) == 1
                    loc_ind = 1;
                else
                    % square look
                    loc_ind = length( nbinters) - tmp(1) + 1;
                end
            else
                loc_ind = 1;
            end

            zerocross = GetCrossPnt( app, v, ref);
            if ~isempty( zerocross)
                last_pnt = max( [loc( loc_ind), zerocross(end)]);
            else
                last_pnt = loc( loc_ind);
            end
        end

        
        function ind = EventIndex( app, val)
            % app.EventsListBox.Value, app.EventsListBox.ItemsData
            % value, label, location; type
            if ~istable( val) && ~iscell( val)
                val = {val};
            end

            if istable( val)
                ind = nan( size( val, 1), 1);
            else
                ind = nan( size( val));
            end

            for k = 1 : length( ind)
                if istable( val)
                    lbl = val.Label{ k};
                    loc = val.Location( k);
                    typ = val.Type{ k};
                else
                    s = strsplit( val{ k}, ';');
                    lbl = strtrim( s{1});
                    loc = str2double( s{2});
                    typ = strtrim( s{3});
                end
     
                ind( k) = find( app.data.events.Location == loc & ... 
                    ismember( app.data.events.Label, lbl) & ...
                    ismember( app.data.events.Type, typ));
            end
        end


        function ev = Item2Table( ~, val)
            % convert app.EventsListBox.Value to event table
            if ~iscell( val)
                val = {val};
            end

            ev = [];
            for k = 1 : numel( val)
                s = strsplit( val{ k}, ';');
                ev.Label{ k, 1} = strtrim( s{1});
                ev.Location( k, 1) = str2double( s{2});
                ev.Type{ k, 1} = strtrim( s{3});
                ev.Mutable( k, 1) = str2double( strtrim( s{4}));
            end

            ev = struct2table( ev);
        end

        
        function ShowChannels(app, action)
            % action, 'show' or 'hide'
            % app.DispChannelLB
            % app.RemChannelLB

            % channels already shown
            disp_chnl = app.DispChannelLB.Items;
            if ~isempty( disp_chnl) && ~iscell( disp_chnl)
                disp_chnl = {disp_chnl};
            end

            disp_sel = app.DispChannelLB.Value;
            if ~isempty( disp_sel) && ~iscell( disp_sel)
                disp_sel = {disp_sel};
            end

            rem_sel = app.RemlChannelLB.Value;
            if ~isempty( rem_sel) && ~iscell( rem_sel)
                rem_sel = {rem_sel};
            end

            % all channels, app.data.label
            if strcmpi( action, 'show')
                chnl2disp = cat( 1, disp_chnl(:), rem_sel(:));
            else
                chnl2disp = disp_chnl( ~ismember( disp_chnl, disp_sel));
            end
            
            if ~isempty( chnl2disp)
                set( app.DispChannelLB, 'Items', chnl2disp, 'Value', chnl2disp(1));
            else
                set( app.DispChannelLB, 'Items', {}, 'Value', {});
            end

            rem_chnl = app.data.label( ~ismember( app.data.label, chnl2disp));
            if ~isempty( rem_chnl)
                set( app.RemlChannelLB, 'Items', rem_chnl, 'Value', {});
            else
                set( app.RemlChannelLB, 'Items', {}, 'Value', {});
            end


            if ~isempty( app.data.index)
                cur_label = app.data.label( app.data.index);
            else
                if ~isempty( chnl2disp)
                    cur_label = chnl2disp(1);
                else
                    cur_label = {};
                end
            end

            if ~isempty( cur_label)
                [~, app.data.index] = ismember( cur_label, app.data.label);
            end

            Redraw(app);
        end % show channels


    end % private method


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            InitializeData( app);

            app.misc = [];
            app.misc.fontsize = 14;
            app.misc.fontname = 'Arial';
            app.misc.fontcolor = 'k';
            app.misc.fontweight = 'normal';
            app.misc.minior_stepsize = 0.8;            
            % 'left', 'right', 'center'
            app.misc.box_align = lower(app.BoxAlignDD.Value);
            % name, [r, g, b]
            app.misc.event_type = {'R Peak', [1, 0, 0];
                'P Peak', [0.3 1 0];
                'Q Trough', [0.4, 0.1 0];
                'S Trough', [0.1 0.6 0.6];
                'T Peak', [0.01 0.2 0.5];
                'Unset', [0.3 0.4 0.01]};
            app.misc.time_format = 'seconds';            
            InitializeUI( app);


            % input data structure
            opt = struct( 'mat', [], ...
                'srate', 1,...
                'events', [],...
                'label', [],...
                'type', []);

            opt.label = {};

            opt = G_SparseArgs( opt, varargin);

            % % Example data
            % if isempty( opt.mat)
            %     d = load( '~/Desktop/Data/ECoG_SZ/Analysis/Rest/Raw/CropPreproc/preproc_hk002');
            %     opt.srate = d.reref_ecog.fsample;
            %     opt.mat = [d.resp; d.ecg];
            % end 

            LoadData( app, opt);                  
            UpdateWin( app, 0, true);

            % app.TabGroup.SelectedTab = app.EventsTab;
            app.RemlChannelLB.Items = {};
            % uiwait( app.UIFigure);
        end

        % Button pushed function: Button_Forward
        function Button_ForwardPushed(app, event)
            UpdateWin( app, app.TimeSpinner.Value);
        end

        % Button pushed function: Button_Backward
        function Button_BackwardPushed(app, event)
            UpdateWin( app, -app.TimeSpinner.Value);
        end

        % Button pushed function: Button_ForwardMinor
        function Button_ForwardMinorPushed(app, event)
            UpdateWin( app, app.misc.minior_stepsize*app.TimeSpinner.Value);
        end

        % Button pushed function: Button_BackwardMinor
        function Button_BackwardMinorPushed(app, event)
            UpdateWin( app, -1*app.misc.minior_stepsize*app.TimeSpinner.Value);
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            if isempty( app.data.mat)
                return;
            end

            % % % double click to add an event
            % % ax_xy = app.UIAxes.CurrentPoint( 1, 1:2);
            % % click_ax = prod( ax_xy(2) - [0, 1]) <= 0 && prod( ax_xy(1) - app.nav.data_win) <= 0;
            % if strcmpi( app.UIFigure.SelectionType, 'open') && IClickMainAxes( app)
            %     % use inhale onset amplitude
            %     app.CrossPointButton.Value = 1;               
            %     val = [];
            %     if ~isempty( app.data.events)
            %         loc = find( app.data.t( app.data.events.Location) < app.misc.cursor, 1, "last");
            %         if ~isempty( loc)
            %             val = app.data.mat( app.data.index, app.data.events.Location( loc));
            %         end
            %     end
            % 
            %     if ~isempty( val)
            %         app.CrossValEF.Value = val;
            %     end
            % 
            %     AddEvButtonPushed(app, []);
            %     return;
            % end
            
            % left click to move the box
            left_mclick = strcmpi( app.UIFigure.SelectionType, 'normal');
            nav_xy = app.UIAxesN.CurrentPoint( 1, 1:2)';
            if all( prod( nav_xy - [0, 1], 2) <= 0) && left_mclick
                N = length( app.data.t);
                loc = round( nav_xy( 1) * N);
                loc = max( [1, loc]);
                loc = min( [loc, N]);
                s = app.data.t( loc);
                UpdateWin( app, s, true);
            end

            ax_xy = app.UIAxes.CurrentPoint( 1, 1:2)';
            xy_in = ax_xy - [app.UIAxes.XLim; app.UIAxes.YLim];
            if all( prod( xy_in, 2) <= 0) && left_mclick
                app.misc.cursor = ax_xy(1);
                UpdateWin( app, 0);
            end

            % % right click to move an event
            % right_click = strcmpi( app.UIFigure.SelectionType, 'Alt');
            % cur_ev_mutable = ~isempty( app.misc.current_ev) && app.misc.current_ev.mutable ~= 0;
            % if app.misc.start_move && right_click && cur_ev_mutable
            %     val = max( [app.nav.data_win(1), ax_xy(1)]);
            %     val = min( [app.nav.data_win(2), val]);
            %     app.misc.current_ev.h.XData = val;
            %     app.misc.current_ev.h.YData = app.data.mat( app.data.index, dsearchn( app.data.t(:), val));
            % 
            %     % update event location
            %     idx = app.misc.current_ev.index;
            %     orig_ev = app.data.events( idx, :);
            %     prec = 1e-12;
            %     if ~(prod( ax_xy(1) - app.nav.data_win) > prec || prod( ax_xy(2) - app.UIAxes.YLim) > prec)
            %         app.data.events.Location( idx) = dsearchn( app.data.t(:), val);
            %     end
            % 
            %     IUpdateEvents( app, 'update', app.data.events( idx, :), orig_ev);
            % end
            
            app.misc.start_move = false;
        end

        % Value changed function: TimeSpinner
        function TimeSpinnerValueChanged(app, event)
            app.SpinnerBoxSize.Limits = [0, app.TimeSpinner.Value];
            UpdateWin( app, 0);
        end

        % Value changed function: AmplitudeSpinner
        function AmplitudeSpinnerValueChanged(app, event)
            Redraw( app);
        end

        % Value changed function: SpinnerBoxSize
        function SpinnerBoxSizeValueChanged(app, event)
            UpdateWin( app, 0);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;

            % box for detection
            box_lim = app.SpinnerBoxSize.Limits;
            box_step = diff( box_lim)/10;
            box_step = min( [0.05, box_step]);
            box_val = app.SpinnerBoxSize.Value;

            % time window
            t_win = app.TimeSpinner.Value;
            t_lim = app.TimeSpinner.Limits;
            t_step = 0.5 * t_win;

            % amplitude scale
            y_scale = app.AmplitudeSpinner.Value;
            y_lim = app.AmplitudeSpinner.Limits;
            y_step = 10;

            switch lower( key)
                case 'z'
                    app.SpinnerBoxSize.Value = max( [box_lim(1), box_val - box_step]);
                    TimeSpinnerValueChanged( app);

                case 'd'
                    app.SpinnerBoxSize.Value = min( [box_lim(2), box_val + box_step]);
                    TimeSpinnerValueChanged( app);

                case 'f'
                    % Button_ForwardPushed( app);
                    Button_ForwardMinorPushed( app);

                case 'b'
                    Button_BackwardPushed( app);

                case 'a'
                    AddEvButtonPushed( app);

                case 't'
                    % increase time window
                    app.TimeSpinner.Value = max( [t_lim(1), t_win - t_step]);
                    TimeSpinnerValueChanged( app);
                    
                case 'r'
                    % decrease time window
                    app.TimeSpinner.Value = min( [t_lim(2), t_win + t_step]);
                    TimeSpinnerValueChanged( app);

                case 'g'
                    % change y scale
                    app.AmplitudeSpinner.Value = min( [y_lim(2), y_scale + y_step]);
                    Redraw( app);

                case 'h'  
                    % decresae y scale
                    app.AmplitudeSpinner.Value = max( [y_lim(1), y_scale - y_step]);
                    Redraw( app);

                case 'e'
                    MoveEvent( app, 'down');

                case 'y'
                    MoveEvent( app, 'up');

                case 's'
                    SaveMenuSelected( app);

                case 'q'
                    app.evs = app.data.events;
                    uiresume( app.UIFigure);
                    CloseMenuSelected( app);
            end
        end

        % Button pushed function: AddEvTypeButton
        function AddEvTypeButtonPushed(app, event)
            % Add a new event type
            x = strsplit( app.EvTypeEditField.Value, ';');
            x = cellfun( @strtrim, x, 'un', 0);
            x( cellfun( @isempty, x)) = [];
            x = unique( x, 'stable');
            all_type = app.EvTypeListBox.Items;
            x( ismember( x, all_type)) = [];
            if isempty( x)
                set( app.StatusLabel, 'Fontcolor', 'b', ...
                    'Text', 'No new event type to add.')
                return;
            end

            app.EvTypeListBox.Items = [all_type, x(:)'];
            tmp = cell( length( x), 2);
            for k = 1 : length( x)
                tmp{ k, 1} = x{ k};
                tmp{ k, 2} = rand( 1, 3);
            end
            app.misc.event_type = [app.misc.event_type; tmp];
        end

        % Button pushed function: RmEvTypeButton
        function RmEvTypeButtonPushed(app, event)
            % Remove an event type
            app.StatusLabel.Text = 'Not yet implemented.';
            answer = questdlg( 'Are you sure to delete selected event', ...
                'Remove event type', 'Yes', 'No', 'No');

            if ~strcmpi( answer, 'yes')
                return;
            end
            
        end

        % Button pushed function: MvEvTypeUpButton
        function MvEvTypeUpButtonPushed(app, event)
            IMoveItems( app, 'EvTypeListBox', 'up');
            [~, loc] = ismember( app.EvTypeListBox.Items, app.misc.event_type( :, 1));
            app.misc.event_type = app.misc.event_type( loc, :);
        end

        % Button pushed function: DownButton
        function MvEvTypeDownButtonPushed(app, event)
            IMoveItems( app, 'EvTypeListBox', 'down');
            [~, loc] = ismember( app.EvTypeListBox.Items, app.misc.event_type( :, 1));
            app.misc.event_type = app.misc.event_type( loc, :);
        end

        % Value changed function: EvTypeListBox
        function EvTypeListBoxValueChanged(app, event)
            value = app.EvTypeListBox.Value;
            if ~iscell( value) || length( value) == 1
                [~, loc] = ismember( value, app.misc.event_type( :, 1));
                ck = app.misc.event_type{ loc, 2};
                txt = '';
            else
                ck = 'w';
                txt = 'm color';
            end

            app.EventColorLabel.BackgroundColor = ck;
            app.EventColorLabel.Text = txt;
        end

        % Button pushed function: ColorButton
        function ColorButtonPushed(app, event)
            % Change event type color
            value = app.EvTypeListBox.Value;
            if isempty( value)
                return;
            end

            c = uisetcolor;
            if isscalar( c)
                return;
            end

            [~, loc] = ismember( value, app.misc.event_type( :, 1));
            app.misc.event_type{ loc, 2} = c;
            EvTypeListBoxValueChanged( app);
        end

        % Button pushed function: AddEvButton
        function AddEvButtonPushed(app, event)
            if isempty( app.data.mat)
                return;
            end

            % app.misc.box.xdata
            xlo = min( app.misc.box.xdata);
            xhi = max( app.misc.box.xdata);

            prec = 1e-10;
            ind = find( app.data.t >= xlo - prec & app.data.t <= xhi + prec);
            if ~isempty( ind)
                v = app.data.mat( app.data.index, ind);
                if app.PeakButton.Value == 1
                    [~, midx] = max( v);

                elseif app.TroughButton.Value == 1
                    [~, midx] = min( v);

                elseif app.LastTroughButton.Value == 1
                    midx = find( islocalmin( v), 1, 'last');
                    if isempty( midx)
                        midx = length( v);
                    end

                    tmp = find( v( midx + 1 : end) > max( v( 1 : midx)), 1, 'first');
                    if isempty( tmp)
                        tmp = 0;
                    end

                    midx = midx + tmp - 1;

                elseif app.LastPeakButton.Value == 1
                    im = find( islocalmax( v), 1, 'last');
                    if ~any( im)
                        midx = length( v);
                    else
                        midx = find( v( im + 1 : end) < min( v( 1:im)), 1, 'first');
                        if isempty( midx)
                            midx = im;
                        else
                            midx = im + midx(1) + 1;
                        end
                    end

                elseif app.AmplitudechangeButton.Value == 1
                    ipt = findchangepts( v);
                    if isempty( ipt)
                        midx = length( v);
                    else
                        midx = ipt(1);
                    end

                elseif app.CrossPointButton.Value == 1
                    midx = GetCrossPnt( app, v, app.CrossValEF.Value);
                    if ~isempty( midx)
                        midx = midx( end);
                    else
                        midx = length( v);
                    end

                else
                    [~, ~, midx] = SingleCrossThreshold( app, v, app.CrossValEF.Value);

                end

                ev_loc = midx + ind(1) - 1;

            else
                % no data points within box
                if xlo > app.data.t( end)
                    ev_loc = [];

                else
                    l1 = find( app.data.t < xlo, 1, 'last');
                    l2 = find( app.data.t > xhi, 1, 'first');

                    if isempty( l1) && isempty( l2)
                        ev_loc = dsearchn( app.data.t(:), xlo);

                    else
                        l1_gt_l2 = diff( app.data.mat( app.data.index, [l1, l2])) < 0;
                        search_pk = app.PeakButton.Value == 1;
                        if search_pk && l1_gt_l2 || ~(search_pk ~= 1 && ~l1_gt_l2)
                            ev_loc = l1;
                        else
                            ev_loc = l2;
                        end
                    end
                end
            end 

            ev_type = app.EvTypeListBox.Value;
            if ~iscell( ev_type) % one item was selected
                ev_type = {ev_type};
            end
            ev_type = ev_type(:);
            N = numel( ev_type);

            ev2add = [];
            ev2add.Label = repmat( app.data.label( app.data.index), N, 1);
            ev2add.Location = ev_loc * ones( N, 1);
            ev2add.Type = ev_type;
            ev2add.Mutable = 1;
            ev2add = struct2table( ev2add);
            IUpdateEvents( app, 'add', ev2add);
        end

        % Value changed function: EventsListBox
        function EventsListBoxValueChanged(app, event)
            value = app.EventsListBox.Value;
            loc = EventIndex( app, value);
            if length( value) == 1
                % move time window
                % [~, loc] = ismember( value, app.EventsListBox.ItemsData);                
                t = app.data.t( app.data.events.Location( loc));
                if t < app.nav.win(1) || t > app.nav.win(2)
                    t = t - diff( app.nav.win)/2;
                    t = max( [t, app.data.t(1)]);
                    UpdateWin( app, t, true);
                end
            end

            Redraw( app);
            
            if isempty( loc)
                return;
            end

            typ = unique( app.data.events.Type( loc), 'stable');
            app.StatusLabel.Text = strjoin( typ, '; ');
            N = numel( app.data.events.Location);
            loc2 = loc + 1;
            loc2( loc2 > N) = N;
            d = app.data.events.Location( loc2) - app.data.events.Location( loc);
            d = d/app.data.srate;
            if isscalar( d)
                if abs( d) < eps
                    txt = 'Unable to calculate duration.';
                else
                    txt = sprintf( 'Duration: %g s', d);
                end
            else
                txt = sprintf( 'Range: %g - %g s', min(d), max(d));
            end

            app.Label.Text = txt;
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            if isempty( app.data.mat)
                return;
            end

            ax_xy = app.UIAxes.CurrentPoint( 1, 1:2)';
            xy_in = ax_xy - [app.UIAxes.XLim; app.UIAxes.YLim];
            xy_in = all( prod( xy_in, 2) <= 0);
            app.misc.start_move = false;
            if xy_in && strcmpi( app.UIFigure.SelectionType, 'Alt')
                app.misc.start_move = true;
                set( app.StatusLabel, 'Text', 'Adjusting events...');
            end
           
            % update seltected channel index in raw data matrix
            RetrieveYlim( app, ax_xy);
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if isempty( app.data.mat)
                return;
            end

            if ~IClickMainAxes( app)
                return;
            end

            % Change location of selected event
            ax_xy = app.UIAxes.CurrentPoint( 1, 1:2)';
            
            cur_ev_mutable = ~isempty( app.misc.current_ev) && ...
                ~isempty( app.misc.current_ev.index) ...
                && app.misc.current_ev.mutable ~= 0;
            right_click  = strcmpi( app.UIFigure.SelectionType, 'Alt');
            if app.misc.start_move && cur_ev_mutable && right_click
                val = max( [app.nav.data_win(1), ax_xy(1)]);
                val = min( [app.nav.data_win(2), val]);
                app.misc.current_ev.h.XData = val;
                app.misc.current_ev.h.YData = app.data.mat( app.data.index, dsearchn( app.data.t(:), val));
            end
        end

        % Button pushed function: UpdateEvTypeButton
        function UpdateEvTypeButtonPushed(app, event)
            ev2update = Item2Table( app, app.EventsListBox.Value);
            if ~isempty( ev2update) && any( ev2update.Mutable == 0)
                set( app.StatusLabel, 'FontColor', 'r', ...
                    'Text', 'Selected events cannot be updated.');
                return;
            end

            IUpdateEvents( app, 'update', ev2update);
            EventsListBoxValueChanged( app);
        end

        % Button pushed function: RmEvButton
        function RmEvButtonPushed(app, event)
            ev2rm = Item2Table( app, app.EventsListBox.Value);
            if ~isempty( ev2rm) && any( ev2rm.Mutable == 0)
                set( app.StatusLabel, 'FontColor', 'r', ...
                    'Text', 'Selected events cannot be removed.');
                return;
            end

            IUpdateEvents( app, 'remove', ev2rm);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % app.UIFigure.Visible = 'off';
            [file, p] = uigetfile( {'.mat'; '.txt'});
            % app.UIFigure.Visible = 'on';
            if file == 0
                return;
            end

            if p == 0
                p = pwd;
            end

            app.data.filename = fullfile( p, file);
            LoadData( app, app.data.filename);
        end

        % Callback function
        function BoxalignmentButtonGroupSelectionChanged(app, event)
            if app.CenterBoxAlignButton.Value == 1
                app.misc.box_align = 'center';
            elseif app.RightBoxAlignButton.Value == 1
                app.misc.box_align = 'right';
            else
                app.misc.box_align = 'left';
            end

            UpdateWin( app, 0, false);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            SaveResults( app);
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            if isempty( app.data.filename)
                [nam, p] = uiputfile( '*.mat', 'Save the project file:');
            else
                [p, nam] = filepart( app.data.filename);
                nam = [nam, '_EventMarker.mat'];
                save_file = fullfile( p, nam);
                [nam, p] = uiputfile( '*.mat', 'Save the project file:', save_file);
            end
                        
            if isequal( nam, 0) || isequal( p, 0)
                disp('User pressed cancel')
                return;
            end
            
            app.data.save_file = fullfile( p, nam);
            SaveResults( app);
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            IRecoverEvents( app);
        end

        % Menu selected function: CloseMenu
        function CloseMenuSelected(app, event)
            ICloseLog( app);
            InitializeData( app);            
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            SaveResults( app);
            close( app.UIFigure);
        end

        % Menu selected function: EventsMenu
        function EventsMenuSelected(app, event)
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            app.UIAxes.CurrentPoint( 1, 1:2);
        end

        % Value changed function: BoxAlignDD
        function BoxAlignDDValueChanged(app, event)
            value = lower( app.BoxAlignDD.Value);            
            if contains( value, 'center')
                app.misc.box_align = 'center';
            elseif contains( value, 'right')
                app.misc.box_align = 'right';
            else
                app.misc.box_align = 'left';
            end

            UpdateWin( app, 0, false);
        end

        % Callback function
        function PreEvIdxCBValueChanged(app, event)
            value = app.PreEvIdxCB.Value;
            if value == 0
                return;
            end

            x = app.CrossValEF.Value;
            txt = '';
            if x <= 0
                x = 1;
                txt = [txt, ' was set to 1.'];
            end

            if x ~= round( x)
                x = round( x);
                txt = [txt, 'was rounded.'];
            end

            app.CrossValEF.Value = x;
            if ~isempty( txt)
                app.StatusLabel.Text = ['Cross point ', txt];
                app.StatusLabel.FontColor = 'b';
            end
        end

        % Callback function
        function CrossValEFValueChanged(app, event)
            PreEvIdxCBValueChanged( app, []);
        end

        % Value changed function: DispChannelLB
        function DispChannelLBValueChanged(app, event)
            % [~, app.data.index] = ismember( app.DispChannelLB.Value, app.data.label);
            % Redraw( app);
        end

        % Menu selected function: LogFileMenu
        function LogFileMenuSelected(app, event)
            set( app.StatusLabel, 'FontColor', 'b', ...
                'Text', sprintf( 'Log file: %s', app.data.logfile));
        end

        % Menu selected function: SourceFileMenu
        function SourceFileMenuSelected(app, event)
            txt = 'No source file was specified.';
            if ~isempty( app.data.source_file)
                txt = sprintf( 'Source file: %s', app.data.source_file);
            end
            set( app.StatusLabel, 'FontColor', 'b', 'Text', txt);
        end

        % Clicked callback: DispChannelLB
        function DispChannelLBClicked(app, event)
            item = event.InteractionInformation.Item;
            if isempty( item)
                return;
            end
            
            item = app.DispChannelLB.Items{ item};
            [~, app.data.index] = ismember( item, app.data.label);
            Redraw( app);
        end

        % Button pushed function: DetectAllBtn
        function DetectAllBtnButtonPushed(app, event)
            % Detect all events within this window
            if isempty( app.data.mat)
                return;
            end
            % 
            % d_win = app.nav.data_win;
            % % d_win_samp = app.data.srate * d_win;
            % prec = 1e-12;
            % samp_ind = app.data.t >= d_win(1) - prec & app.data.t <= d_win(2) + prec;
            % d_win_samp = [find( samp_ind>0, 1, 'first'), find( samp_ind>0, 1, 'last')];
            % s_loc = app.nav.data_ind(1);
            % e_loc = app.nav.data_ind(2);
            % v = app.data.mat( app.data.index, s_loc:e_loc);

            s_loc = 1;
            e_loc = size( app.data.mat, 2);
            v = app.data.mat( app.data.index, s_loc:e_loc);
            [avg, sd] = normfit( v);
            v = (v - avg) / sd;
            pk_thr = app.RPeakZthrEF.Value;
            if any( v > pk_thr)
                min_dist = app.data.srate * app.MinRRDistEF.Value;
                [~, pkloc] = findpeaks( v, 'MinPeakDistance', min_dist, 'MinPeakHeight', pk_thr);
                N = numel( pkloc);
                e = [];
                e.Label = repmat( app.data.label( app.data.index), [N, 1]);
                e.Location = pkloc(:) + s_loc - 1;
                e.Type = repmat( app.EvTypeListBox.Items(1), [N, 1]);
                e.Mutable = ones( N, 1);
                e = struct2table( e);
                IUpdateEvents( app, 'add', e);
            else
                set( app.StatusLabel, 'Text', 'No peaks detected.', 'FontColor', 'r');
            end
        end

        % Value changed function: ScaleinWindowButton
        function ScaleinWindowButtonValueChanged(app, event)
            Redraw( app);
        end

        % Callback function
        function AddFiltECGButtonPushed(app, event)
            if isempty( app.data.mat)
                return;
            end

            if isempty( app.DispChannelLB.Value)
                return;
            end

            % multiple channel selected
            if iscell( app.DispChannelLB.Value)
                return;
            end
            
            ia = ismember( app.data.label, 'filt_ecg');
            if any( ia)
                chn_loc = find( ia);
            else
                chn_loc = numel( app.data.label) + 1;
            end

            % use currently hilighted channel
            y = ECG_MODWT( app.data.mat( app.data.index, :), ...
                app.data.srate, app.MODWTLevelEF.Value, str2num( app.ReconLevelEF.Value)); %#ok<ST2NM>
            app.data.label{ chn_loc} = 'filt_ecg';
            app.data.mat( chn_loc, :) = y;
            app.DispChannelLB.Items = app.data.label;
            Redraw( app);

            if any( ia)
                set( app.StatusLabel, 'Text', 'filt_ecg already exists. It was updated');
            end
        end

        % Callback function
        function OutlierListBoxClicked(app, event)
            item = event.InteractionInformation.Item;            
            app.EventsListBox.Value = app.OutlierListBox.ItemsData( item);
            EventsListBoxValueChanged( app);
        end

        % Button pushed function: ShowLabel
        function ShowLabelPushed(app, event)
            ShowChannels(app, 'show');
        end

        % Button pushed function: HideLabel
        function HideLabelPushed(app, event)
            ShowChannels(app, 'hide');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~app.misc.data_saved
                SaveResults(app);
            end

            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseMenuSelected, true);
            app.CloseMenu.Text = 'Close';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.EventsMenu = uimenu(app.UIFigure);
            app.EventsMenu.MenuSelectedFcn = createCallbackFcn(app, @EventsMenuSelected, true);
            app.EventsMenu.Text = 'Events';
            app.SaveMenu = uimenu(app.EventsMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.EventsMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As';
            app.LoadMenu = uimenu(app.EventsMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.LogFileMenu = uimenu(app.HelpMenu);
            app.LogFileMenu.MenuSelectedFcn = createCallbackFcn(app, @LogFileMenuSelected, true);
            app.LogFileMenu.Text = 'Log File';
            app.SourceFileMenu = uimenu(app.HelpMenu);
            app.SourceFileMenu.MenuSelectedFcn = createCallbackFcn(app, @SourceFileMenuSelected, true);
            app.SourceFileMenu.Text = 'Source File';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.GridLayout5 = uigridlayout(app.GridLayout);
            app.UIAxesN = uiaxes(app.GridLayout5);
            app.UIAxesN.XTick = [];
            app.UIAxesN.YTick = [];
            app.Button_Backward = uibutton(app.GridLayout5, 'push');
            app.Button_Backward.ButtonPushedFcn = createCallbackFcn(app, @Button_BackwardPushed, true);
            app.Button_Backward.Text = '<<';
            app.Button_BackwardMinor = uibutton(app.GridLayout5, 'push');
            app.Button_BackwardMinor.ButtonPushedFcn = createCallbackFcn(app, @Button_BackwardMinorPushed, true);
            app.Button_BackwardMinor.Text = '<';
            app.Button_Forward = uibutton(app.GridLayout5, 'push');
            app.Button_Forward.ButtonPushedFcn = createCallbackFcn(app, @Button_ForwardPushed, true);
            app.Button_Forward.Text = '>>';
            app.Button_ForwardMinor = uibutton(app.GridLayout5, 'push');
            app.Button_ForwardMinor.ButtonPushedFcn = createCallbackFcn(app, @Button_ForwardMinorPushed, true);
            app.Button_ForwardMinor.Text = '>';
            app.StatusLabel = uilabel(app.GridLayout);
            app.StatusLabel.Tooltip = {'Status bar.'};
            app.StatusLabel.Text = 'Label2';
            app.GridLayout13 = uigridlayout(app.GridLayout);
            app.TimeSpinnerLabel = uilabel(app.GridLayout13);
            app.TimeSpinnerLabel.Text = 'Time';
            app.TimeSpinner = uispinner(app.GridLayout13);
            app.TimeSpinner.Limits = [2.22044604925031e-16 Inf];
            app.TimeSpinner.ValueChangedFcn = createCallbackFcn(app, @TimeSpinnerValueChanged, true);
            app.TimeSpinner.Value = 15;
            app.AmplitudeSpinner = uispinner(app.GridLayout13);
            app.AmplitudeSpinner.Step = 5;
            app.AmplitudeSpinner.Limits = [1 Inf];
            app.AmplitudeSpinner.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSpinnerValueChanged, true);
            app.AmplitudeSpinner.Value = 100;
            app.ScaleinWindowButton = uibutton(app.GridLayout13, 'state');
            app.ScaleinWindowButton.ValueChangedFcn = createCallbackFcn(app, @ScaleinWindowButtonValueChanged, true);
            app.ScaleinWindowButton.Text = 'Scale in Window';
            app.ResetButton = uibutton(app.GridLayout13, 'push');
            app.ResetButton.Text = 'Reset';
            app.StartClockEditFieldLabel = uilabel(app.GridLayout13);
            app.StartClockEditFieldLabel.Text = 'Start Clock:';
            app.StartClockEditField = uieditfield(app.GridLayout13, 'text');
            app.EndClockEditFieldLabel = uilabel(app.GridLayout13);
            app.EndClockEditFieldLabel.Text = 'End Clock';
            app.EndClockEditField = uieditfield(app.GridLayout13, 'text');
            app.GridLayout10 = uigridlayout(app.GridLayout);
            app.GotoLabel = uilabel(app.GridLayout10);
            app.GotoLabel.Text = 'Go to:';
            app.EditField4_3 = uieditfield(app.GridLayout10, 'text');
            app.GridLayout14 = uigridlayout(app.GridLayout);
            app.GridLayout15 = uigridlayout(app.GridLayout14);
            app.DisplayLabel = uilabel(app.GridLayout15);
            app.DisplayLabel.Text = 'Display';
            app.AllLabel = uilabel(app.GridLayout15);
            app.AllLabel.Text = 'All';
            app.DispChannelLB = uilistbox(app.GridLayout15);
            app.DispChannelLB.ValueChangedFcn = createCallbackFcn(app, @DispChannelLBValueChanged, true);
            app.DispChannelLB.ClickedFcn = createCallbackFcn(app, @DispChannelLBClicked, true);
            app.RemlChannelLB = uilistbox(app.GridLayout15);
            app.Button_2 = uibutton(app.GridLayout15, 'push');
            app.Button_2.Text = '<<';
            app.ShowLabel = uibutton(app.GridLayout15, 'push');
            app.ShowLabel.ButtonPushedFcn = createCallbackFcn(app, @ShowLabelPushed, true);
            app.ShowLabel.Text = '<';
            app.HideLabel = uibutton(app.GridLayout15, 'push');
            app.HideLabel.ButtonPushedFcn = createCallbackFcn(app, @HideLabelPushed, true);
            app.HideLabel.Text = '>';
            app.Button_5 = uibutton(app.GridLayout15, 'push');
            app.Button_5.Text = '>>';
            app.ClearButton = uibutton(app.GridLayout15, 'push');
            app.ClearButton.Text = 'Clear';
            app.LockCheckBox = uicheckbox(app.GridLayout15);
            app.LockCheckBox.Text = 'Lock';
            app.TrialDropDown = uidropdown(app.GridLayout15);
            app.TrialDropDown.Items = {'1'};
            app.TrialDropDown.Value = '1';
            app.TrialLabel = uilabel(app.GridLayout15);
            app.TrialLabel.Text = 'Trial';
            app.GridLayout8 = uigridlayout(app.GridLayout15);
            app.EvTypeListBox = uilistbox(app.GridLayout8);
            app.EvTypeListBox.Items = {'R Peak', 'P Peak', 'Q Trough', 'S Trough', 'T Peak', 'Unset'};
            app.EvTypeListBox.Multiselect = 'on';
            app.EvTypeListBox.ValueChangedFcn = createCallbackFcn(app, @EvTypeListBoxValueChanged, true);
            app.EvTypeListBox.Value = {'R Peak'};
            app.MvEvTypeUpButton = uibutton(app.GridLayout8, 'push');
            app.MvEvTypeUpButton.ButtonPushedFcn = createCallbackFcn(app, @MvEvTypeUpButtonPushed, true);
            app.MvEvTypeUpButton.Text = 'Up';
            app.RmEvTypeButton = uibutton(app.GridLayout8, 'push');
            app.RmEvTypeButton.ButtonPushedFcn = createCallbackFcn(app, @RmEvTypeButtonPushed, true);
            app.RmEvTypeButton.Text = 'Delete';
            app.DownButton = uibutton(app.GridLayout8, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @MvEvTypeDownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.EvTypeEditField = uieditfield(app.GridLayout8, 'text');
            app.EventtypeLabel = uilabel(app.GridLayout8);
            app.EventtypeLabel.Text = 'Event type';
            app.EventColorLabel = uilabel(app.GridLayout8);
            app.EventColorLabel.Text = 'Color';
            app.ColorButton = uibutton(app.GridLayout8, 'push');
            app.ColorButton.ButtonPushedFcn = createCallbackFcn(app, @ColorButtonPushed, true);
            app.ColorButton.Text = 'Color';
            app.ChannelDispDD = uidropdown(app.GridLayout8);
            app.ChannelDispDD.Items = {'All Channels', 'Selected Channel'};
            app.ChannelDispDD.Value = 'All Channels';
            app.AddEvTypeButton = uibutton(app.GridLayout8, 'push');
            app.AddEvTypeButton.ButtonPushedFcn = createCallbackFcn(app, @AddEvTypeButtonPushed, true);
            app.AddEvTypeButton.Text = 'Add';
            app.EventDispDD = uidropdown(app.GridLayout8);
            app.EventDispDD.Items = {'All Types', 'Selected Type'};
            app.EventDispDD.Value = 'All Types';
            app.DisplayLabel_2 = uilabel(app.GridLayout8);
            app.DisplayLabel_2.Text = 'Display';
            app.GridLayout16 = uigridlayout(app.GridLayout14);
            app.EventsListBox = uilistbox(app.GridLayout16);
            app.EventsListBox.Multiselect = 'on';
            app.EventsListBox.ValueChangedFcn = createCallbackFcn(app, @EventsListBoxValueChanged, true);
            app.EventsListBox.Value = {'Item 1'};
            app.Label5 = uilabel(app.GridLayout16);
            app.Label5.Text = 'Label5';
            app.CurrentEditFieldLabel = uilabel(app.GridLayout16);
            app.CurrentEditFieldLabel.Text = 'Current';
            app.CurrentEditField = uieditfield(app.GridLayout16, 'numeric');
            app.UpdateEvTypeButton = uibutton(app.GridLayout16, 'push');
            app.UpdateEvTypeButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateEvTypeButtonPushed, true);
            app.UpdateEvTypeButton.Text = 'Update';
            app.RmEvButton = uibutton(app.GridLayout16, 'push');
            app.RmEvButton.ButtonPushedFcn = createCallbackFcn(app, @RmEvButtonPushed, true);
            app.RmEvButton.Text = 'Remove';
            app.EventsListBoxLabel = uilabel(app.GridLayout16);
            app.EventsListBoxLabel.Text = 'Events';
            app.GridLayout12 = uigridlayout(app.GridLayout14);
            app.BoxsLabel = uilabel(app.GridLayout12);
            app.BoxsLabel.Text = 'Box (s):';
            app.SpinnerBoxSize = uispinner(app.GridLayout12);
            app.SpinnerBoxSize.Step = 0.01;
            app.SpinnerBoxSize.Limits = [0.01 Inf];
            app.SpinnerBoxSize.ValueChangedFcn = createCallbackFcn(app, @SpinnerBoxSizeValueChanged, true);
            app.SpinnerBoxSize.Value = 0.2;
            app.BoxAlignDD = uidropdown(app.GridLayout12);
            app.BoxAlignDD.Items = {'Right', 'Left', 'Center'};
            app.BoxAlignDD.ValueChangedFcn = createCallbackFcn(app, @BoxAlignDDValueChanged, true);
            app.BoxAlignDD.Value = 'Center';
            app.GridLayout17 = uigridlayout(app.GridLayout14);
            app.CrosspointEditFieldLabel = uilabel(app.GridLayout17);
            app.CrosspointEditFieldLabel.Text = 'Cross point';
            app.CrossValEF = uieditfield(app.GridLayout17, 'numeric');
            app.PreEvIdxCB = uicheckbox(app.GridLayout17);
            app.PreEvIdxCB.Tooltip = {'Use the value of last N event'};
            app.PreEvIdxCB.Text = 'Previous Event';
            app.ButtonGroup = uibuttongroup(app.GridLayout17);
            app.PeakButton = uiradiobutton(app.ButtonGroup);
            app.PeakButton.Text = 'Peak';
            app.PeakButton.Value = true;
            app.TroughButton = uiradiobutton(app.ButtonGroup);
            app.TroughButton.Text = 'Trough';
            app.AmplitudechangeButton = uiradiobutton(app.ButtonGroup);
            app.AmplitudechangeButton.Text = 'Amplitude change';
            app.LastPeakButton = uiradiobutton(app.ButtonGroup);
            app.LastPeakButton.Text = 'Last  Peak';
            app.LastTroughButton = uiradiobutton(app.ButtonGroup);
            app.LastTroughButton.Text = 'Last Trough';
            app.CrossPointButton = uiradiobutton(app.ButtonGroup);
            app.CrossPointButton.Text = 'Cross Point';
            app.UnknownButton = uiradiobutton(app.ButtonGroup);
            app.UnknownButton.Text = 'Unknown';
            app.GridLayout18 = uigridlayout(app.GridLayout14);
            app.MindistsLabel = uilabel(app.GridLayout18);
            app.MindistsLabel.Text = 'Min dist. (s)';
            app.MinRRDistEF = uieditfield(app.GridLayout18, 'numeric');
            app.MinRRDistEF.Value = 0.4;
            app.DetectAllBtn = uibutton(app.GridLayout18, 'push');
            app.DetectAllBtn.ButtonPushedFcn = createCallbackFcn(app, @DetectAllBtnButtonPushed, true);
            app.DetectAllBtn.Text = 'Auto Detect';
            app.RPeakZthrEF = uieditfield(app.GridLayout18, 'numeric');
            app.RPeakZthrEF.Value = 1.5;
            app.EditField_2Label = uilabel(app.GridLayout18);
            app.EditField_2Label.Tooltip = {'Peak z score threhsold.'};
            app.EditField_2Label.Text = 'Threshhold';
            app.AddEvButton = uibutton(app.GridLayout18, 'push');
            app.AddEvButton.ButtonPushedFcn = createCallbackFcn(app, @AddEvButtonPushed, true);
            app.AddEvButton.Text = 'Add';
            app.Label = uilabel(app.GridLayout);
            app.ShowEventDropDownLabel = uilabel(app.UIFigure);
            app.ShowEventDropDownLabel.Text = 'Show Event';
            app.ShowChannelDropDownLabel = uilabel(app.UIFigure);
            app.ShowChannelDropDownLabel.Text = 'Show Channel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EMarker(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end